classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WestCambridgeDeliveryMapPanel  matlab.ui.container.Panel
        CustomOrderPanel               matlab.ui.container.Panel
        PickupLat                      matlab.ui.control.NumericEditField
        PickupLong                     matlab.ui.control.NumericEditField
        LatitudeLabel                  matlab.ui.control.Label
        LongitudeLabel                 matlab.ui.control.Label
        PickupLocationLabel            matlab.ui.control.Label
        PickupPresets                  matlab.ui.control.ListBox
        UploadMissionButton            matlab.ui.control.Button
        DeliveryLat                    matlab.ui.control.NumericEditField
        DeliveryLong                   matlab.ui.control.NumericEditField
        LatitudeLabel_2                matlab.ui.control.Label
        LongitudeLabel_2               matlab.ui.control.Label
        DeliveryLocationLabel          matlab.ui.control.Label
        DeliveryPresets                matlab.ui.control.ListBox
        DroneLat                       matlab.ui.control.NumericEditField
        DroneLong                      matlab.ui.control.NumericEditField
        LatitudeLabel_3                matlab.ui.control.Label
        LongitudeLabel_3               matlab.ui.control.Label
        DroneLocationLabel             matlab.ui.control.Label
        DroneAltitude                  matlab.ui.control.NumericEditField
        AltitudemLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        gx;
        droneMarker;
        droneRoute;
        gcObj;
        
        groundControl = [52.20954726481548, 0.09000709627050027];
        
        coord_map = containers.Map();
        nodes = [];
        distance_cache = zeros(300, 300);
        gcDistance = zeros(1, 300);
        
        % nodeIndex form
        deliveries = [];
        currentMissions = [];
        currentMissionIndex = 0;
        pendingMissions = [];
        
        presetLocs;
        presetLats;
        presetLongs;
    end
    
    properties (Access = public)
    end
    
    methods (Access = public)
        %         function updateGUI(app, varargin)
        %             % create objects to get runtime values of blocks in Simulink
        %             rto0 = get_param([bdroot,'/Guidance Logic/Full Guidance Logic/dispX'],'RuntimeObject');
        %             rto1 = get_param([bdroot,'/Guidance Logic/Full Guidance Logic/dispY'],'RuntimeObject');
        %
        %             % update GUI
        %             app.LatitudeEditField.Value = string(rto0.InputPort(1).Data);
        %             app.LongitudeEditField.Value = string(rto1.InputPort(1).Data);
        %
        %             CamCenter = [52.2116,0.0869];
        %             tempOffset = -[-186,-105];
        %             dronePos = CamCenter + (tempOffset + [rto0.InputPort(1).Data,rto1.InputPort(1).Data])/(40*10^6) * 1000;
        %             app.gx.MapCenter = dronePos;
        %             app.droneMarker.LatitudeData = dronePos(1);
        %             app.droneMarker.LongitudeData = dronePos(2);
        %             hold(app.gx,"on");
        %         end
        
        function updateGUI_DronePosition(app, dronePos)
            app.droneMarker.LatitudeData = dronePos(1);
            app.droneMarker.LongitudeData = dronePos(2);
            
            app.DroneLat.Value = dronePos(1);
            app.DroneLong.Value = dronePos(2);
            app.DroneAltitude.Value = dronePos(3);
            hold(app.gx,"on");
        end
        
        function displayDeliveries(app)
            routes = [];
            for idx = 1:size(app.currentMissions,1)
                start_idx = app.currentMissions(idx,1);
                end_idx = app.currentMissions(idx,2);
                disp('Index check');
                disp(start_idx);
                disp(end_idx);
                waypoints = computeRRTStarRoute(app.nodes(start_idx,:),app.nodes(end_idx,:));
                if waypoints<0
                    disp(idx);
                    disp(waypoints);
                    disp(start_idx);
                    disp(end_idx);
                else
                    routes = cat(1,routes,waypoints);
                end
            end
            if ~isempty(routes)
                app.droneRoute.LatitudeData = routes(:,1);
                app.droneRoute.LongitudeData = routes(:,2);
                hold(app.gx,"on");
            end
            
        end
        
        function results = runScheduler(app)
            
            %             app.debugMe();
            %
            solution = scheduleMissions(app.coord_map, app.nodes, app.distance_cache, app.gcDistance, app.pendingMissions);
            [app.deliveries, app.pendingMissions] = solution{:};
            
            % copy delivery missions and
            % add intermediate missions from delivery to pickup travel
            for idx = 1:size(app.deliveries,1)
                app.currentMissions(2*idx-1,:) = app.deliveries(idx,:);
                interStart = app.deliveries(idx,2);
                % last delivery is followed by mission back to ground control
                interGoal = 1;
                % if not last mission, use pickup location of next delivery as goal
                if(idx < size(app.deliveries,1))
                    interGoal = app.deliveries(idx,1);
                end
                app.currentMissions(2*idx,:) = [interStart, interGoal];
            end
            disp('First mission');
            disp([1,app.deliveries(1,1)]);
            app.currentMissions = cat(1,[1,app.deliveries(1,1)], app.currentMissions);
            disp('Current Missions');
            disp(app.currentMissions);
        end
        
        function waypoints = generateWaypoints(app,mission)
            % takes mission in nodeIndex form and outputs computed
            % waypoints in LatLon form
            disp('MISSION');
            disp(mission);
            startPoint = app.nodes(mission(1),:);
            goalPoint = app.nodes(mission(2),:);
            waypoints = computeRRTStarRoute(startPoint, goalPoint);
        end
        
        function debugMe(app)
            %             disp(app.nodes);
            %             disp(app.currentMissions);
            %             disp(app.pendingMissions);
            %             disp(app.currentMissionIndex);
            
            disp(size(app.pendingMissions, 1));
            disp(size(app.nodes,1));
            disp(app.pendingMissions);
        end
        
        function results = nextMission(app)
            
            app.currentMissionIndex = app.currentMissionIndex + 1;
            
            %             app.debugMe();
            
            % if all scheduled missions completed and there are missions
            % pending
            if app.currentMissionIndex > size(app.currentMissions,1)
                % reset mission queue
                app.currentMissionIndex = 1;
                app.currentMissions = [];
                
                if ~isempty(app.pendingMissions)
                    app.runScheduler();
                    app.displayDeliveries();
                end
            end
            
            % if there are missions to complete
            if ~isempty(app.currentMissions)
                mission = app.currentMissions(app.currentMissionIndex, :);
                points = app.generateWaypoints(mission);
                
                if points < 0
                    % this mission is not possible
                    app.nextMission();
                else
                    % add altitude 10 to every point except last (landing)
                    % altitudes = ones(size(points,1),1) * 10;
                    % altitudes(size(points,1)) = 0;
                    % waypoints = [points altitudes];
                    % Tom already adds altitudes ^^^
                    waypoints = points;
                    
                    disp('WAYPOINT');
                    disp(waypoints);
                    
                    % send mission
                    app.gcObj.sendWaypoints(waypoints);
                end
            end
            
            
            disp('Starting Next Mission');
            if app.currentMissionIndex > size(app.currentMissions,1)
                disp('Out of scheduled missions!');
            else
                disp(app.currentMissions(app.currentMissionIndex,:));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize plotters
            app.gx = geoaxes(app.WestCambridgeDeliveryMapPanel ,"Basemap","streets","MapCenter",app.groundControl,"ZoomLevel",17);
            hold(app.gx,"on");
            app.droneMarker = geoplot(app.gx,app.groundControl(1),app.groundControl(2),'or');
            hold(app.gx,"on");
            app.droneRoute = geoplot(app.gx,[0],[0],"LineWidth",2);
            hold(app.gx,"on");
            
            % initialize presets
            app.presetLocs = {'Custom','University Nursery','Veterinary School','Maxwell Centre','Computer Lab','The Lawns'};
            app.presetLats = containers.Map(app.presetLocs,[0, 52.20959898617805,52.210926398165455,52.209930406566144,52.21131142512676,52.20979093037732]);
            app.presetLongs = containers.Map(app.presetLocs,[0, 0.08957008944066791,0.08904540085527186,0.0914800902281618,0.09120338879205804,0.09545336215218546]);
            app.PickupPresets.Items = app.presetLocs;
            app.DeliveryPresets.Items = app.presetLocs;
            
            % initialize scheduler caches
            app.gcObj = GroundControl(@app.updateGUI_DronePosition, @app.nextMission);
            app.coord_map(num2str(app.groundControl)) = 1;
            app.nodes(1,:) = app.groundControl;
        end

        % Button pushed function: UploadMissionButton
        function UploadMissionButtonPushed(app, event)
            app.UploadMissionButton.Enable = "off";
            
            newMission = [[app.PickupLat.Value app.PickupLong.Value];[app.DeliveryLat.Value app.DeliveryLong.Value]];
            
            pause(1);
            app.UploadMissionButton.Enable = "on";
            
            % caches distances AND adds to pendingMissions queue
            solution = updateState(newMission, app.groundControl, app.coord_map, app.nodes, app.distance_cache, app.gcDistance, app.pendingMissions);
            [app.coord_map, app.nodes, app.distance_cache, app.gcDistance, app.pendingMissions] = deal(solution{:});
            
            % if no missions currently in progress, trigger next mission
            if isempty(app.currentMissions)
                app.nextMission;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %             disp(app.gcObj.getPosition());
            app.gcObj.delete();
            delete(app);
        end

        % Value changed function: PickupPresets
        function PickupPresetsValueChanged(app, event)
            value = app.PickupPresets.Value;
            if(strcmp(value,'Custom'))
                app.PickupLat.Enable = 'on';
                app.PickupLong.Enable = 'on';
            else
                app.PickupLat.Enable = 'off';
                app.PickupLong.Enable = 'off';
                app.PickupLat.Value = app.presetLats(value);
                app.PickupLong.Value = app.presetLongs(value);
            end
        end

        % Value changed function: DeliveryPresets
        function DeliveryPresetsValueChanged(app, event)
            value = app.DeliveryPresets.Value;
            if(strcmp(value,'Custom'))
                app.DeliveryLat.Enable = 'on';
                app.DeliveryLong.Enable = 'on';
            else
                app.DeliveryLat.Enable = 'off';
                app.DeliveryLong.Enable = 'off';
                app.DeliveryLat.Value = app.presetLats(value);
                app.DeliveryLong.Value = app.presetLongs(value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WestCambridgeDeliveryMapPanel = uipanel(app.UIFigure);
            app.WestCambridgeDeliveryMapPanel.Title = 'West Cambridge Delivery Map';
            app.CustomOrderPanel = uipanel(app.UIFigure);
            app.CustomOrderPanel.Title = 'Custom Order';
            app.PickupLat = uieditfield(app.CustomOrderPanel, 'numeric');
            app.PickupLong = uieditfield(app.CustomOrderPanel, 'numeric');
            app.LatitudeLabel = uilabel(app.CustomOrderPanel);
            app.LatitudeLabel.Text = 'Latitude';
            app.LongitudeLabel = uilabel(app.CustomOrderPanel);
            app.LongitudeLabel.Text = 'Longitude';
            app.PickupLocationLabel = uilabel(app.CustomOrderPanel);
            app.PickupLocationLabel.Text = 'Pickup Location';
            app.PickupPresets = uilistbox(app.CustomOrderPanel);
            app.PickupPresets.ValueChangedFcn = createCallbackFcn(app, @PickupPresetsValueChanged, true);
            app.UploadMissionButton = uibutton(app.CustomOrderPanel, 'push');
            app.UploadMissionButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMissionButtonPushed, true);
            app.UploadMissionButton.Text = {'Upload'; 'Mission'};
            app.DeliveryLat = uieditfield(app.CustomOrderPanel, 'numeric');
            app.DeliveryLong = uieditfield(app.CustomOrderPanel, 'numeric');
            app.LatitudeLabel_2 = uilabel(app.CustomOrderPanel);
            app.LatitudeLabel_2.Text = 'Latitude';
            app.LongitudeLabel_2 = uilabel(app.CustomOrderPanel);
            app.LongitudeLabel_2.Text = 'Longitude';
            app.DeliveryLocationLabel = uilabel(app.CustomOrderPanel);
            app.DeliveryLocationLabel.Text = 'Delivery Location';
            app.DeliveryPresets = uilistbox(app.CustomOrderPanel);
            app.DeliveryPresets.ValueChangedFcn = createCallbackFcn(app, @DeliveryPresetsValueChanged, true);
            app.DroneLat = uieditfield(app.UIFigure, 'numeric');
            app.DroneLat.Editable = 'off';
            app.DroneLong = uieditfield(app.UIFigure, 'numeric');
            app.DroneLong.Editable = 'off';
            app.LatitudeLabel_3 = uilabel(app.UIFigure);
            app.LatitudeLabel_3.Text = 'Latitude';
            app.LongitudeLabel_3 = uilabel(app.UIFigure);
            app.LongitudeLabel_3.Text = 'Longitude';
            app.DroneLocationLabel = uilabel(app.UIFigure);
            app.DroneLocationLabel.Text = 'Drone Location';
            app.DroneAltitude = uieditfield(app.UIFigure, 'numeric');
            app.DroneAltitude.Editable = 'off';
            app.AltitudemLabel = uilabel(app.UIFigure);
            app.AltitudemLabel.Text = 'Altitude (m)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end